classdef ProjektSynchrofazory < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        AkcjeMenu           matlab.ui.container.Menu
        DodajurzdzenieMenu  matlab.ui.container.Menu
        UsuurzdzenieMenu    matlab.ui.container.Menu
        MapaMenu            matlab.ui.container.Menu
        UstawieniaMenu      matlab.ui.container.Menu
        GridLayout          matlab.ui.container.GridLayout
        TabGroup            matlab.ui.container.TabGroup
        WykresykoloweTab    matlab.ui.container.Tab
        GridForPlots        matlab.ui.container.GridLayout
        WidokszczegowyTab   matlab.ui.container.Tab
        GridLayout2         matlab.ui.container.GridLayout
        UIAxes4             matlab.ui.control.UIAxes
        UIAxes3             matlab.ui.control.UIAxes
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        DataQueueRecv; % Property used to get data from function running on other thread
        DataQueueSend; % Property used to put data to function running on other thread
        Pool;  % Pool of threads
        Task; 
        NumberOfPlotsPerPage=4; % Ilosc wykresów na jednej stronie
        ColorOfPlots=[0.00 0.45 0.74]; % Kolor wykresów
        IpList=["192.168.22.103"];
        Port=4713;
        ListOfPMU=[]';
        NumberOfAxes;
        ListOfPolarPlots=[];

    end
    
    properties (Access = private)
        OptionsDialog % Okno dialogowe dla zmiany ustawień
        AddDialog
    end
   
    methods (Access = public,Static)
         
        function AddAxes(app, axes)
            u = [5 3 -4 -3 5];
            v = [1 5 3 -2 -6];
            compass(axes, u, v);
        end

        function ReciveUDP(queueOut,queueIn,IpList,Port)%function used to open and recive data datagram from UDP port
            connection = udpport("datagram","Ipv4","LocalPort",Port,"LocalHost",IpList,"OutputDatagramSize",96,"EnablePortSharing",true);
            while true
                if queueIn.QueueLength>0
                    break;
                elseif connection.DatagramsAvailableFcnCount>0
                    send(queueOut,read(connection,1,"uint8"));
                end
            end
            delete(connection);
        end

    end
    methods (Access = public)
        function DataQueueListener(app,data)

           
           tempPMU=PMU();
           tempPMU.InsertFrame(data);
        end
        
        function UpdateOptions(app)
            
        end
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             app.Pool = parpool('local', 2);
%             app.DataQueueRecv=parallel.pool.DataQueue;
%             app.DataQueueSend=parallel.pool.DataQueue;
%             afterEach(app.DataQueueRecv,@(data) DataQueueListener(app,data));
%             parfeval(app.Pool,@UDPconne,0,app.DataQueueRecv,app.DataQueueSend,app.IpList,app.Port);

              
              for i=1:16
              handle=CustomPolarPlot(app.GridForPlots);
              %handle.Layout.Row = 1;
              %handle.Layout.Column = 1;
              handle.UpdateTimeQuality(true);
              handle.UpdatePolarPlot(10,30);
              app.ListOfPolarPlots(end+1)=handle;
              end


        end

        % Menu selected function: MapaMenu
        function MapaMenuSelected(app, event)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

%             send(app.DataQueueSend,"Die");
%             delete(gcp("nocreate"));
            delete(app.OptionsDialog);
            delete(app)
        end

        % Menu selected function: UstawieniaMenu
        function UstawieniaMenuSelected(app, event)
            app.OptionsDialog = UstawieniaDialog(app);
        end

        % Menu selected function: DodajurzdzenieMenu
        function DodajurzdzenieMenuSelected(app, event)
            app.AddDialog = DodajUrzadzenieDialog(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AkcjeMenu = uimenu(app.UIFigure);
            app.AkcjeMenu.Text = 'Akcje';
            app.DodajurzdzenieMenu = uimenu(app.AkcjeMenu);
            app.DodajurzdzenieMenu.MenuSelectedFcn = createCallbackFcn(app, @DodajurzdzenieMenuSelected, true);
            app.DodajurzdzenieMenu.Text = 'Dodaj urządzenie';
            app.UsuurzdzenieMenu = uimenu(app.AkcjeMenu);
            app.UsuurzdzenieMenu.Text = 'Usuń urządzenie';
            app.MapaMenu = uimenu(app.UIFigure);
            app.MapaMenu.MenuSelectedFcn = createCallbackFcn(app, @MapaMenuSelected, true);
            app.MapaMenu.Text = 'Mapa';
            app.UstawieniaMenu = uimenu(app.UIFigure);
            app.UstawieniaMenu.MenuSelectedFcn = createCallbackFcn(app, @UstawieniaMenuSelected, true);
            app.UstawieniaMenu.Text = 'Ustawienia';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.WykresykoloweTab = uitab(app.TabGroup);
            app.WykresykoloweTab.Title = 'Wykresy kołowe';
            app.GridForPlots = uigridlayout(app.WykresykoloweTab);
            app.WidokszczegowyTab = uitab(app.TabGroup);
            app.WidokszczegowyTab.Title = 'Widok szczegółowy';
            app.GridLayout2 = uigridlayout(app.WidokszczegowyTab);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout2);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout2);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjektSynchrofazory

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end